classdef VIONWAKE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup01                 matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        UITable2                   matlab.ui.control.Table
        ParticleTrajectoriesTab    matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        SimulationControlPanel     matlab.ui.container.Panel
        NewseedCheckBox            matlab.ui.control.CheckBox
        ReplayButton               matlab.ui.control.Button
        PlayLamp                   matlab.ui.control.Lamp
        PlayButton                 matlab.ui.control.StateButton
        SimulationParametersPanel  matlab.ui.container.Panel
        PowerSpinner               matlab.ui.control.Spinner
        PowerSpinnerLabel          matlab.ui.control.Label
        PressureSpinner            matlab.ui.control.Spinner
        PressureSpinnerLabel       matlab.ui.control.Label
        GravitySpinner             matlab.ui.control.Spinner
        GravitySpinnerLabel        matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Parameters;
        Dust; 
        Kernels;

        % Callbacks
        play                    = false;
        trace                   = false;
        record                  = false;
        tab                     = 'Main';
        setup                   = false;
        

        % Timing globals
        TOTAL_TIME              = 0.0;
        firstFrame              = true;
        TIME                    = 0;
        drawRate                = 30;
        tau
        numTimeSteps

        % CUDA KERNELS
        pcryAcc;
        pcryPos;

        % Axes objects
        dustAxes;
        wakeAxes;

        % OUTPUT AND INPUT FILES
        fileParam               = 'parameters.txt';
    end
    
    methods (Access = private) 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup app
            % Instantiate parameter class
            app.Parameters = vdriadParam(app.fileParam);

            % Instantiate dust class
            app.Dust = vdriadDust(app.Parameters);

            % Format Window
            vdriadFormatMain(app);

            % Setup display
            vdriadDrawCell(app.UIAxes,app.Parameters);
            vdriadDrawDust(app);

            % Initialize Kernels
            vdriadSetKernels(app);
            % We need to turn this into a function
            tic
            vdriadCalculateForces(app.Dust,app.Parameters,app.pcryAcc);
            vdriadCalculatePositions(app.Dust,app.Parameters,app.pcryPos,app.TOTAL_TIME);
            app.tau = toc
            app.Dust.loadInitialConditions;
            drawPeriod = 1/app.drawRate;
            app.numTimeSteps = floor(drawPeriod/app.tau)
        end

        % Value changed function: PlayButton
        function Main(app, event)
            drawCount = 0;

            app.play = app.PlayButton.Value;
            
            % Change the color of the lamp
            if app.play == true
                app.PlayLamp.Color = 'green';
            else
                app.PlayLamp.Color = 'red';
            end

            % Keep the simulation running
            while app.play == true 
                % this is for debugging, we have to turn it into a function
                
                fprintf("x velocity: %e\n",app.Dust.Velocity.Device.x);
                fprintf("y velocity: %e\n",app.Dust.Velocity.Device.y);
                fprintf("x acceleration: %e\n",app.Dust.Acceleration.Device.x);
                fprintf("y acceleration: %e\n",app.Dust.Acceleration.Device.y);
                app.Parameters.ION_DEBYE
                app.Parameters.ELECTRON_DEBYE
                % disp(app.Dust.Velocity.Host.y);
                % disp(app.Dust.Velocity.Host.z);
                % disp(app.Dust.Position.Host.z);
                % disp(app.Dust.Acceleration.Host.z);
                % disp(-2*app.Parameters.E_FIELD_COEFF*(app.Dust.Position.Host.z - app.Parameters.SHEATH_HEIGHT)./app.Dust.Mass.Host.*app.Dust.Charge.Host);
                % disp(app.Dust.Position.Host.x);
                % disp(app.Parameters);
                % Calculate one timestep
                vdriadCalculateForces(app.Dust,app.Parameters,app.pcryAcc);
                vdriadCalculatePositions(app.Dust,app.Parameters,app.pcryPos,app.TOTAL_TIME);


                % Draw Particles
                if drawCount == 0
                   % Copy data from the GPU to the host
                   app.Dust.memoryCopy('DtoH');
                   vdriadDrawDust(app);
                end

                % app.Dust.memoryCopy('DtoH');
                % vdriadDrawDust(app);
                drawnow

                % Increase counters
                app.TOTAL_TIME = app.TOTAL_TIME + app.Parameters.TIME_STEP;
             
                drawCount = mod(drawCount+1,app.numTimeSteps);
                
            end
        end

        % Value changing function: GravitySpinner
        function GravitySpinnerValueChanging(app, event)
            app.Parameters.GRAVITY = event.Value;
        end

        % Value changing function: PressureSpinner
        function PressureSpinnerValueChanging(app, event)
            app.Parameters.GAS_PRESSURE = event.Value;
        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            % Reset timer
            app.TOTAL_TIME = 0.0;

            if app.NewseedCheckBox.Value
                % Generate new seed for particles
                app.Dust.generateParticles(app.Parameters);
            else
                % Retrieve and load initial conditions
                app.Dust.loadInitialConditions;
               
                % CORRECT THIS <==============
                % Add a function to copy to the GPU here
            end

            % Draw first frame
            vdriadDrawDust(app)
        end

        % Value changed function: PowerSpinner
        function PowerSpinnerValueChanged(app, event)
            app.params.CELL_POWER = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'GEC RF Reference Cell')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationParametersPanel = uipanel(app.UIFigure);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.GravitySpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.GravitySpinnerLabel.Text = 'Gravity ';
            app.GravitySpinner = uispinner(app.SimulationParametersPanel);
            app.GravitySpinner.ValueChangingFcn = createCallbackFcn(app, @GravitySpinnerValueChanging, true);
            app.PressureSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PressureSpinnerLabel.Text = 'Pressure ';
            app.PressureSpinner = uispinner(app.SimulationParametersPanel);
            app.PressureSpinner.ValueChangingFcn = createCallbackFcn(app, @PressureSpinnerValueChanging, true);
            app.PowerSpinnerLabel = uilabel(app.SimulationParametersPanel);
            app.PowerSpinnerLabel.Text = 'Power ';
            app.PowerSpinner = uispinner(app.SimulationParametersPanel);
            app.PowerSpinner.ValueChangedFcn = createCallbackFcn(app, @PowerSpinnerValueChanged, true);
            app.SimulationControlPanel = uipanel(app.UIFigure);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.PlayButton = uibutton(app.SimulationControlPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @Main, true);
            app.PlayButton.Text = 'Play';
            app.PlayLamp = uilamp(app.SimulationControlPanel);
            app.ReplayButton = uibutton(app.SimulationControlPanel, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.NewseedCheckBox = uicheckbox(app.SimulationControlPanel);
            app.NewseedCheckBox.Text = 'New seed';
            app.TabGroup01 = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup01);
            app.Tab.Title = 'Tab';
            app.UITable2 = uitable(app.Tab);
            app.UITable2.ColumnName = {'Parameter'; 'Value'};
            app.UITable2.RowName = {};
            app.ParticleTrajectoriesTab = uitab(app.TabGroup01);
            app.ParticleTrajectoriesTab.Title = 'Particle Trajectories';
            app.UITable = uitable(app.ParticleTrajectoriesTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIONWAKE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end